classdef GUI_version < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TrainButton                     matlab.ui.control.Button
        TestButton                      matlab.ui.control.Button
        OpenCSVfileButton               matlab.ui.control.Button
        NumberofHiddenNeuronsEditFieldLabel  matlab.ui.control.Label
        NumberofHiddenNeuronsEditField  matlab.ui.control.NumericEditField
        NumberofInputDaysEditFieldLabel  matlab.ui.control.Label
        NumberofInputDaysEditField      matlab.ui.control.NumericEditField
        NumberofPredictedDaysEditFieldLabel  matlab.ui.control.Label
        NumberofPredictedDaysEditField  matlab.ui.control.NumericEditField
        BiasValueEditFieldLabel         matlab.ui.control.Label
        BiasValueEditField              matlab.ui.control.NumericEditField
        ShowpredictionforsamplenumberEditFieldLabel  matlab.ui.control.Label
        ShowpredictionforsamplenumberEditField  matlab.ui.control.NumericEditField
        UIAxes                          matlab.ui.control.UIAxes
        CreateELMButton                 matlab.ui.control.Button
        ActivationFunctionEditFieldLabel  matlab.ui.control.Label
        ActivationFunctionEditField     matlab.ui.control.EditField
        ProportionsTrainDataTestDataSliderLabel  matlab.ui.control.Label
        ProportionsTrainDataTestDataSlider  matlab.ui.control.Slider
        NumberoftestsamplesEditFieldLabel  matlab.ui.control.Label
        NumberoftestsamplesEditField    matlab.ui.control.NumericEditField
        timeEditFieldLabel              matlab.ui.control.Label
        timeEditField                   matlab.ui.control.NumericEditField
    end


    properties (Access = private)
        file % csv file after parsing it to table
        elm  % our elm
        testX
        testY
        predictionoftestY
        trainX
        trainY
        nFeatures
    end


    methods (Access = private)

        % Button pushed function: OpenCSVfileButton
        function OpenCSVfileButtonPushed(app, event)
            filename=uigetfile;
            if(filename~=0)
              app.file = ParseCSV(filename);
              app.file = app.file(:,2:end-1);
              app.CreateELMButton.Enable='on';
            end
        end

        % Button pushed function: CreateELMButton
        function CreateELMButtonPushed(app, event)
            app.elm=ELM_Class(app.NumberofInputDaysEditField.Value,...
                app.NumberofHiddenNeuronsEditField.Value,...
                app.NumberofPredictedDaysEditField.Value,size(app.file,2),...
                app.ActivationFunctionEditField.Value,app.BiasValueEditField.Value);
            app.nFeatures = size(app.file,2);
            app.TrainButton.Enable='on';
            [oX,oY]=rearrangeData(app.elm,app.file);
            [app.testX,app.testY,app.trainX,app.trainY] = setProportionsOfData(oX,oY,app.ProportionsTrainDataTestDataSlider.Value);
            app.NumberoftestsamplesEditField.Value=size(app.testY,1);
            % change upper limit to size of testsamples
            %app.ShowpredictionforsamplenumberEditField.UpperLimitInclusive=size(app.testY,1);
        end

        % Button pushed function: TrainButton
        function TrainButtonPushed(app, event)
            tic
            app.elm = train(app.elm,app.trainX,app.trainY);
            app.timeEditField.Value = toc;
            app.TestButton.Enable= 'on';
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
            app.predictionoftestY = predict(app.elm,app.testX);
            app.ShowpredictionforsamplenumberEditField.Enable='on';
            
        end

        % Value changed function: 
        % ShowpredictionforsamplenumberEditField
        function ShowpredictionforsamplenumberEditFieldValueChanged(app, event)
            value = app.ShowpredictionforsamplenumberEditField.Value;
            t=1:getnumofOutdays(app.elm);
            %legend not working as it should fix it later
            % for now it only shows predicted open prices vs real open prices
            %legend(app.UIAxes.Legend, 'predicted Open','real Open');
           %app.UIAxes.Legend = legend('a','b');
           %legend(
            plot(app.UIAxes,t,app.predictionoftestY(value,1: app.nFeatures : end) ,'b'...
                ,t,app.testY(value,1: app.nFeatures : end),'r');
            
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TrainButton = uibutton(app.UIFigure, 'push');
            app.TrainButton.ButtonPushedFcn = createCallbackFcn(app, @TrainButtonPushed, true);
            app.TrainButton.Enable = 'off';
            app.TrainButton.Text = 'Train';
            app.TestButton = uibutton(app.UIFigure, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Enable = 'off';
            app.TestButton.Text = 'Test';
            app.OpenCSVfileButton = uibutton(app.UIFigure, 'push');
            app.OpenCSVfileButton.ButtonPushedFcn = createCallbackFcn(app, @OpenCSVfileButtonPushed, true);
            app.OpenCSVfileButton.Text = 'Open CSV file';
            app.NumberofHiddenNeuronsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofHiddenNeuronsEditFieldLabel.Text = 'Number of Hidden Neurons';
            app.NumberofHiddenNeuronsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofHiddenNeuronsEditField.Limits = [2 10000];
            app.NumberofHiddenNeuronsEditField.ValueDisplayFormat = '%.0f';
            app.NumberofHiddenNeuronsEditField.Value = 30;
            app.NumberofInputDaysEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofInputDaysEditFieldLabel.Text = 'Number of Input Days';
            app.NumberofInputDaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofInputDaysEditField.Limits = [1 Inf];
            app.NumberofInputDaysEditField.ValueDisplayFormat = '%.0f';
            app.NumberofInputDaysEditField.Value = 30;
            app.NumberofPredictedDaysEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofPredictedDaysEditFieldLabel.Text = 'Number of Predicted Days';
            app.NumberofPredictedDaysEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofPredictedDaysEditField.Limits = [1 Inf];
            app.NumberofPredictedDaysEditField.ValueDisplayFormat = '%.0f';
            app.NumberofPredictedDaysEditField.Value = 10;
            app.BiasValueEditFieldLabel = uilabel(app.UIFigure);
            app.BiasValueEditFieldLabel.Text = 'Bias Value';
            app.BiasValueEditField = uieditfield(app.UIFigure, 'numeric');
            app.BiasValueEditField.ValueDisplayFormat = '%.0f';
            app.BiasValueEditField.Value = 1;
            app.ShowpredictionforsamplenumberEditFieldLabel = uilabel(app.UIFigure);
            app.ShowpredictionforsamplenumberEditFieldLabel.Text = 'Show prediction for sample number:';
            app.ShowpredictionforsamplenumberEditField = uieditfield(app.UIFigure, 'numeric');
            app.ShowpredictionforsamplenumberEditField.Limits = [0 Inf];
            app.ShowpredictionforsamplenumberEditField.ValueDisplayFormat = '%.0f';
            app.ShowpredictionforsamplenumberEditField.ValueChangedFcn = createCallbackFcn(app, @ShowpredictionforsamplenumberEditFieldValueChanged, true);
            app.ShowpredictionforsamplenumberEditField.Enable = 'off';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Predicted Price vs Real Price')
            xlabel(app.UIAxes, 'Day')
            ylabel(app.UIAxes, 'Price')
            app.CreateELMButton = uibutton(app.UIFigure, 'push');
            app.CreateELMButton.ButtonPushedFcn = createCallbackFcn(app, @CreateELMButtonPushed, true);
            app.CreateELMButton.Enable = 'off';
            app.CreateELMButton.Text = 'Create ELM';
            app.ActivationFunctionEditFieldLabel = uilabel(app.UIFigure);
            app.ActivationFunctionEditFieldLabel.Text = 'Activation Function';
            app.ActivationFunctionEditField = uieditfield(app.UIFigure, 'text');
            app.ActivationFunctionEditField.Value = 'linear';
            app.ProportionsTrainDataTestDataSliderLabel = uilabel(app.UIFigure);
            app.ProportionsTrainDataTestDataSliderLabel.Text = 'Proportions TrainData/TestData';
            app.ProportionsTrainDataTestDataSlider = uislider(app.UIFigure);
            app.ProportionsTrainDataTestDataSlider.Limits = [50 95];
            app.ProportionsTrainDataTestDataSlider.MajorTicks = [50 55 60 65 70 75 80 85 90 95];
            app.ProportionsTrainDataTestDataSlider.Value = 80;
            app.NumberoftestsamplesEditFieldLabel = uilabel(app.UIFigure);
            app.NumberoftestsamplesEditFieldLabel.Enable = 'off';
            app.NumberoftestsamplesEditFieldLabel.Text = 'Number of test samples: ';
            app.NumberoftestsamplesEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberoftestsamplesEditField.Limits = [0 Inf];
            app.NumberoftestsamplesEditField.Editable = 'off';
            app.NumberoftestsamplesEditField.Enable = 'off';
            app.timeEditFieldLabel = uilabel(app.UIFigure);
            app.timeEditFieldLabel.Text = 'time';
            app.timeEditField = uieditfield(app.UIFigure, 'numeric');
            app.timeEditField.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI_version

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end